classdef PIASpectra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PIASpectraUIFigure        matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        ResetButton               matlab.ui.control.Button
        ExportFiguresButton       matlab.ui.control.Button
        NormUITable               matlab.ui.control.Table
        ExportationLabel          matlab.ui.control.Label
        LoadCreateError           matlab.ui.control.Label
        LoadCSVButton             matlab.ui.control.Button
        LoadTDMSButton            matlab.ui.control.Button
        CenterPanel               matlab.ui.container.Panel
        UpdatedSampleTimesLabel   matlab.ui.control.Label
        CurrentSampleTimesLabel   matlab.ui.control.Label
        DecimaterFactorEditField  matlab.ui.control.NumericEditField
        FilterfactorLabel         matlab.ui.control.Label
        FilterSwitch              matlab.ui.control.RockerSwitch
        FilterSwitchLabel         matlab.ui.control.Label
        NextButton                matlab.ui.control.Button
        PrevButton                matlab.ui.control.Button
        SpecPlot                  matlab.ui.control.UIAxes
        NormKinPlot               matlab.ui.control.UIAxes
        KinPlot                   matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        FileMetadataLabel         matlab.ui.control.Label
        Channel                   matlab.ui.control.Label
        ExportSpectraDataButton   matlab.ui.control.Button
        ExportCSVDataButton       matlab.ui.control.Button
        KineticLevelDisplay       matlab.ui.control.Label
        BackgroundLvlDisplay      matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        AddButton                 matlab.ui.control.Button
        Description               matlab.ui.control.Label
        PlotDetailsLabel          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = private)
        Path % Property that holds the path of the folder we are looking at
        rst % Property that holds a boolean check of if we are refreshing already loaded data
        waves % Store list of wavelengths from header names
        LinDataSort % Store the sorted LinData table
        LogDataSort % Store the sorted LogData table
        timeRG % Store the TimeRG table
        dataRG % Store the DataRG table as all the y data values
        Traces % Store the number of traces
        BackgroundLevel % Store BackgroundLvl table
        changeAxes % Store whether or not we are keeping Y-axis fixed
        KinInd % Store index of which file we are looking at
        RGcolor % Stores color array for each plot

        ShadeArea % Array to store 2 vectors for each ROI for starting and end pos.
        TimeWidths % Store time absolute widths for each ROI
        TimeFields % Store time centres for each ROI
        normIndex % Store index of slice to normalize by
        sampleTime % Store the current sample time of the data

        SpecYRange % Store the Y-range of the spectra axes
        FixedYRange % Store the Y-range of kinetic axes
        SpecDataPlot % Store the spec plot data of the specified index
        DataPlot % Hold the kinetics line plot object
        specHandle % Hold the spectra line plot object
        normPlot % Hold the normalized kinetics line plot objects
        checks % Store logical values for UITable checkboxes

        KinRef % Handle to hold kinetics yline
        SpecRef % Handle to hold spectra yline
        NormRef % Handle to hold normalied yline

        XPointsROI % Store ROI objects in cell array
        Plot_Wavelengths % Sorted string2double wavelengths nums

        TimeLinSpecArray % Property to hold sorted table2array time values (1st column)
        SortedYLinArray % Property to hold sorted table2array y values for each set (2:end)
        KinDataArray % Property to hold sorted table2array kin data values
        bkgLevel % Property to hold table2array background level values

        maxWave % Property to hold highest wavelength in file set
        minMinSpecData % Property to hold smallest element in matrix
        maxMaxSpecData % Property to hold largest element in matrix

        r % Hold the factor of r to decimate input kinetics later
        FilteredPlot % Hold the line plot for filtered data
        FilteredX % Holds unchanging filtered x data
        FilteredY % Holds the changing filtered y data
        normFactor % Hold mean value of time slice, either filtered or unfiltered
        FileName % Hold file name of loaded csv file
    end
    
    properties (Access = public)
        Channels % Holds the chosen channel names of all .tdms files
        RedGreen % Holds boolean values to determine colour of Channels text
    end

    methods (Access = private)

        function clearProperties(app)
            % Set all properties back to default values
            app.waves = {};
            app.BackgroundLevel = {};
            app.LinDataSort = {};
            app.LogDataSort = {};

            app.dataRG = {};
            app.timeRG = {};

            app.FixedYRange = [];
            app.SpecYRange = [];
            app.SpecDataPlot = [];

            delete(app.DataPlot);
            delete(app.FilteredPlot);

            app.Channels = strings(0);
            app.RedGreen = [];

            app.UITable.Data = [];
            app.UITable.Data{3,3} = [];
            app.NormUITable.Data = [];
            app.FilterSwitch.Value = "Off";

            app.ShadeArea = [];
            app.Traces = [];
            delete(app.XPointsROI);
            app.KinInd = 1;
            app.Plot_Wavelengths = {};
            app.TimeLinSpecArray = [];
            app.SortedYLinArray = [];

            app.maxWave = [];
            app.minMinSpecData = [];
            app.maxMaxSpecData = [];
            app.r = 10;
            app.FilteredX = [];

            app.checks = [false; true; false];
            app.normIndex = 2;
            app.sampleTime = 0;

            app.TimeFields = [0.3; 5; 10];
            app.TimeWidths = [0.5; 0.5; 0.5];

            cla(app.KinPlot, "reset");
            cla(app.NormKinPlot, "reset");
            cla(app.SpecPlot, "reset");
            app.FileName = "";

            % Set labels on clear
            app.KinPlot.Title.String = "Kinetics";
            app.KinPlot.XLabel.String = "Time (s)";
            app.KinPlot.YLabel.String = "ΔOD or %Abs";
            app.KinPlot.Box = "On";

            app.NormKinPlot.Title.String = "Normalized Kinetics";
            app.NormKinPlot.XLabel.String = "Time (s)";
            app.NormKinPlot.YLabel.String = "Norm. ΔOD or %Abs";
            app.NormKinPlot.Box = "On";

            app.SpecPlot.Title.String = "Spectra";
            app.SpecPlot.XLabel.String = "Wavelength (nm)";
            app.SpecPlot.YLabel.String = "ΔOD or %Abs";
            app.SpecPlot.Box = "On";
        end

        function buildSpecData(app)
            % Preallocate for faster speeds
            app.SpecDataPlot = [];
            row = size(app.SortedYLinArray,2);
            c = size(app.TimeFields,1);
            app.SpecDataPlot = nan(row,c);

            % Set color function with expected num of colors
            app.RGcolor = turbo(size(app.TimeFields,1)+1);
            app.RGcolor = flipud(app.RGcolor);

            % Find the time at the most intense signal
            % % [~,I] = max(app.SortedYLinArray(:,app.KinInd));
            % % maxTime = app.TimeLinSpecArray(I);

            % ANYWAY TO MAKE THIS MORE EFFICIENT??
            % Higher wavelengths have better signal data so use last column
            % for finding the time slice placements
            TF = ischange(app.SortedYLinArray(1:round(end/2),app.Traces),'mean','MaxNumChanges',2);
            IND = find(TF);

            % Set last two time slices based on best signal data
            % app.TimeFields(2) = maxTime - 0.3;
            % app.TimeFields(3) = maxTime + 0.6;
            app.TimeFields(1) = app.TimeLinSpecArray(IND(1))*0.90;
            app.TimeFields(2) = app.TimeLinSpecArray(IND(2))*0.90;
            app.TimeFields(3) = app.TimeLinSpecArray(IND(2))*1.20;

            % Start of plotting in each axes
            Builder(app); % Build specplot data
            updateData(app); % Populate data with first file dataset
            YCheck(app); % Change axes limits and populate range var
            plotKinData(app); % Plot the kinetics data on top of stack
            drawAreas(app); % Draw ROI rectangles
            plotNormalizedData(app); % Plot normalized kinetics
            setNormalizedTable(app); % Finally set normalized table data

            specYCheck(app);
            plotSpecData(app,app.RGcolor); % Plot spectral data
            RGPlot(app,0); % Make sure spectra axes scale is good
        end

        function ROIChangedCallback(app, src, event)
            %%%%%%% Deal with callback; handle mouse input
            % Variables carrying all the new event data
            ind = str2double(src.Tag);

            switch event.EventName
                case "MovingROI"

                    % Conditional variables for different listeners (reshape vs translate)
                    newwidth = src.Position(3);
                    oldwidth = app.TimeWidths(ind);
                    currentCenter = app.TimeFields(ind);

                    % RESHAPE ROI
                    if ~(oldwidth == newwidth)

                        % Then we are manually reshaping left side so adjust the right
                        if ~(src.Position(1) == app.ShadeArea(ind,1))
                            app.TimeWidths(ind) = 2*(currentCenter - src.Position(1));
                            app.XPointsROI(ind,1).Position(3) = app.TimeWidths(ind);

                            % Then we are manually reshaping right side so do nothing
                        else
                            app.XPointsROI(ind,1).Position(3) = app.TimeWidths(ind);
                        end

                        % TRANSLATE ROI
                    else
                        % We only know the x position of left most line.
                        % We need to use this knowldge to calculate new center but keeping same relative width
                        newCenter = src.Position(1) + app.UITable.Data{ind,3}/2;
                        app.TimeWidths(ind) = app.XPointsROI(ind,1).Position(3);
                        app.TimeFields(ind) = newCenter;
                    end

                    Builder(app);
                    RGPlot(app,ind);

                case "ROIMoved"
                    % Update normalized plot if checked time slice is moved
                    if ind == app.normIndex
                        updateNormalizedData(app);
                    end

                    % Update spec axes limits when unclicked
                    specYCheck(app);
            end
        end

        function RGPlot(app,ind)
            % Check if we are updating plot data or just table data
            if ind>= 1
                updateSpecData(app,ind);
            end

            % Update the UITable
            data(:,1) = num2cell(app.checks);
            data(:,2) = num2cell(app.TimeFields);
            data(:,3) = num2cell(app.TimeWidths);

            app.UITable.Data = data;
        end

        function Builder(app,~)
            % Calculate new x data and therefore update spec data
            for i = 1:1:size(app.TimeFields,1)
                CenterTime = app.TimeFields(i);

                StartTime = CenterTime - (app.TimeWidths(i)/2);
                EndTime = CenterTime + (app.TimeWidths(i)/2);

                StartInd = find(StartTime > app.TimeLinSpecArray,1,'last') + 1;
                EndInd = find(EndTime < app.TimeLinSpecArray,1,'first') - 1;

                app.SpecDataPlot(:,i) = mean(app.SortedYLinArray(StartInd:EndInd,:),1);

                if nargin == 1
                    app.ShadeArea(i,1:2) = [StartTime EndTime];
                end
            end

            % Reset max and min elements since data has been reset
            app.minMinSpecData = min(min(app.SpecDataPlot));
            app.maxMaxSpecData = max(max(app.SpecDataPlot));
        end

        function updateData(app)
            % Time variables (x-component stay the same so don't update)
            % Pull most recent data and populate properties
            app.KinDataArray = app.SortedYLinArray(:,app.KinInd);

            % Display the proper wavelengths/position text depending on TAS or TAM data
            app.KineticLevelDisplay.Text = "Displaying kinetic at " + app.Plot_Wavelengths(app.KinInd) + " nm";
            app.BackgroundLvlDisplay.Text = "Background level is " + app.bkgLevel(1,app.KinInd) + " V";
            app.Channel.Text = "Selected channel is " + app.Channels(app.KinInd,1);

            if app.RedGreen(app.KinInd,1) == 1
                app.Channel.FontColor = "g";
            else
                app.Channel.FontColor = "r";
            end
        end

        function plotKinData(app)
            % Plot reference Y = 0 line if Y scale is linear.
            if strcmp(app.KinPlot.YScale,'linear') == 1
                % Make sure to delete previous ylines since we don't
                % clear, then send refline to the bottom for easier access to ROIs
                if ishandle(app.KinRef)
                    delete(app.KinRef)
                end

                app.KinRef = yline(app.KinPlot, 0);
            end

            % Plot the data on axes figure
            delete(app.DataPlot)
            app.DataPlot = line(app.KinPlot, app.TimeLinSpecArray, app.KinDataArray, 'Color','b');
        end

        function plotSpecData(app, RGcolor)
            delete(app.SpecRef);
            delete(app.specHandle);

            % Preallocate graphics objects
            app.specHandle = gobjects(size(app.SpecDataPlot,2),1);

            % Plot new respective wavelengths
            for i = 1:1:size(app.SpecDataPlot,2)
                app.specHandle(i,1) = line(app.SpecPlot, app.Plot_Wavelengths, app.SpecDataPlot(:,i), 'Color', RGcolor(i,:),"Marker",'s');
%                 hold(app.SpecPlot, 'on');
            end
            app.SpecRef = yline(app.SpecPlot,0);
%             hold(app.SpecPlot, 'off');
        end

        function YCheck(app)
            app.FixedYRange = [min(app.KinDataArray) max(app.KinDataArray)];
            app.KinPlot.XLim = [min(app.TimeLinSpecArray) max(app.TimeLinSpecArray)];
            app.KinPlot.YLim = app.FixedYRange;
        end

        function drawAreas(app)
            % Delete any previous data in cell array and preallocate
            sz = size(app.TimeFields,1);

%             mag = floor(log(abs(app.FixedYRange(2)))./log(10));

            height = (app.FixedYRange(2)-app.FixedYRange(1));

            % Set starting x and width for drawing area of each ROI
            x = app.KinPlot.XLim(1);
            w = app.KinPlot.XLim(2) - app.KinPlot.XLim(1);
            y = app.FixedYRange(1);

%             if app.FixedYRange(1) < 0
%                 y = app.FixedYRange(1)*(1.10);
%             else
%                 y = app.FixedYRange(1)*(0.90);
%             end

            % Preallocate graphics objects
            app.XPointsROI = gobjects(sz,1);

            for i = 1:1:sz
                % Create our editable areas (as rectangle objects)
                app.XPointsROI(i,1) = images.roi.Rectangle(app.KinPlot, "Position", [app.ShadeArea(i,1) y app.TimeWidths(i) height], 'Color', app.RGcolor(i,:), 'DrawingArea',[x y w height] ,'Tag', num2str(i), 'UserData', i, 'LineWidth', 1); % 'MarkerSize', 4);

                % Add callbacks for when point if moved
                addlistener(app.XPointsROI(i,1), "MovingROI", @(src,event)ROIChangedCallback(app,src,event));
                addlistener(app.XPointsROI(i,1), "ROIMoved", @(src,event)ROIChangedCallback(app,src,event));
            end
        end

        function updateAreas(app)
            height = (app.FixedYRange(2)-app.FixedYRange(1));

            for i = 1:1:size(app.TimeFields,1)
                app.XPointsROI(i,1).Position = [app.ShadeArea(i,1) app.FixedYRange(1) app.TimeWidths(i) height];
            end
        end

        function addRow(app)
            % Add new field values 5s in front of end, then clear and update app
            ind = length(app.XPointsROI);
            app.TimeFields(end + 1) = app.TimeFields(end) + 5;
            app.TimeWidths(end + 1) = 0.5;
            app.checks(end + 1) = false;


            % Preallocate for faster speeds since size changed
            app.SpecDataPlot = [];
            row = size(app.LinDataSort(:,2:end),2);
            c = size(app.TimeFields, 1);
            app.SpecDataPlot = nan(row,c);

            % More efficient, it doesn't redraw everything, only adds rectangle at end
            Builder(app);

            % Update colour depending on size of table
            app.RGcolor = turbo(size(app.TimeFields,1) + 1);
            app.RGcolor = flipud(app.RGcolor);

            % Draw a new rectangle ROI
            app.XPointsROI(end + 1,1) = images.roi.Rectangle(app.KinPlot, "Position", [app.ShadeArea(end,1) app.FixedYRange(1)*(1.1) app.TimeWidths(end) (app.FixedYRange(2)-app.FixedYRange(1))*(1.50)], 'DrawingArea',[app.KinPlot.XLim(1) app.FixedYRange(1)*1.1 app.KinPlot.XLim(2) - app.KinPlot.XLim(1) (app.FixedYRange(2)-app.FixedYRange(1))*(1.50)] , 'Tag', num2str(ind + 1), 'UserData', ind + 1, 'LineWidth', 1); % 'MarkerSize', 4);
            
            % Assign new colour for each
            for i = 1:size(app.XPointsROI,1)
                app.XPointsROI(i,1).Color = app.RGcolor(i,:);
            end

            % Add callbacks for when ROI is moving for spectra and moved for norm
            addlistener(app.XPointsROI(ind+1,1), "MovingROI", @(src,event)ROIChangedCallback(app,src,event));
            addlistener(app.XPointsROI(ind+1,1), "ROIMoved", @(src,event)ROIChangedCallback(app,src,event));

            plotSpecData(app,app.RGcolor); % Plot spectral data
            RGPlot(app,0); % Plot spectra data and reflect on UITable
        end

        function deleteRow(app, indices)
            % Find index of row
            ind = indices(1);

            % Clear variables connected to row
            app.UITable.Data(ind,:) = [];
            app.TimeFields(ind) = [];
            app.checks(ind) = [];
            app.TimeWidths(ind) = [];
            app.ShadeArea(ind,:) = [];

            % Reapply check mark to same row and update normalized data
            if ind == app.normIndex
                app.checks(ind,:) = true;
                updateNormalizedData(app);
            else
                k = find(app.checks);
                app.normIndex = k;
            end

            % Preallocate for faster speeds since size changed
            app.SpecDataPlot = [];
            row = size(app.LinDataSort(:,2:end),2);
            c = size(app.TimeFields, 1);
            app.SpecDataPlot = nan(row,c);

            % Build new spec data since row got deleted
            Builder(app);
            app.RGcolor = turbo(size(app.TimeFields,1)+1);
            app.RGcolor = flipud(app.RGcolor);

            % Only delete specified ROI and spectra line
            delete(app.XPointsROI(ind,1));
            app.XPointsROI(ind) = [];
            delete(app.specHandle(ind,1));
            app.specHandle(ind) = [];

            % Only reassign tags for efficiency instead of redrawing
            for i = 1:size(app.XPointsROI,1)
                app.XPointsROI(i,1).Tag = num2str(i);
                app.XPointsROI(i,1).Color = app.RGcolor(i,:);
                app.specHandle(i,1).Color = app.RGcolor(i,:);
            end

            % Make sure axes still fits the best and then draw new ROIs
            YCheck(app);
            RGPlot(app,0);
        end

        function plotNormalizedData(app)
            % Clear previous line handles
            delete(app.normPlot);
            delete(app.NormRef);

            try
                % Find average at checked time slice
                locations = app.ShadeArea(app.normIndex,:);
            catch
                uialert(app.PIASpectraUIFigure,"Improper time slice selected. Please try again", "Time Slice Error","Icon","error");
                return
            end

            % Set color function with expected num of colors
            row = size(app.SortedYLinArray,2);
            if row > 7
                Color = turbo(size(app.SortedYLinArray,2));
            else
                Color = turbo(7);
            end
            Color = flipud(Color);

            % Find closest point to find index of time slice
            [~, indx1] = min(abs(app.TimeLinSpecArray - locations(1)));
            [~, indx2] = min(abs(app.TimeLinSpecArray - locations(2)));

            for i = 1:size(app.SortedYLinArray,2)
                % Apply location of checked time slice to find avg
                array2Avg(:,i) = app.SortedYLinArray(indx1:indx2,i);
                app.normFactor(i) = mean(array2Avg(:,i));

                % Apply normaliztion to then be plotted
                NormY(:,i) = app.SortedYLinArray(:,i)/(app.normFactor(i));
            end

            % Preallocate line objects
            app.normPlot = gobjects(size(NormY,2),1);

            % Draw each line
            for i = 1:size(NormY,2)
                app.normPlot(i,1) = line(app.NormKinPlot,app.TimeLinSpecArray,NormY(:,i), 'Color', Color(i,:));
%                 hold(app.NormKinPlot, 'on');
                legs(i) = sprintf("%d nm",app.Plot_Wavelengths(i));

%                 if min(NormY(:,i)) > app.NormKinPlot.YLim(1)
%                     app.NormKinPlot.YLim(1) = min(NormY(:,i));
%                 end
            end

            % Add legend for each trace
            legend(app.NormKinPlot,legs);
            legend(app.NormKinPlot, "boxoff");
%             hold(app.NormKinPlot, 'off');

            % Draw a refline
            app.NormRef = yline(app.NormKinPlot,0);
            app.NormRef.DisplayName = "Ref";
            app.NormRef.HandleVisibility = "Off";

            app.NormKinPlot.Children = [flipud(app.normPlot); app.NormRef];
        end

        function setNormalizedTable(app)
            % Set the column format
            app.NormUITable.ColumnFormat = {'logical','char'};

            % Preallocate table variables
            subset = true(size(app.normPlot,1),1);
            Headers = strings(size(app.normPlot,1),1);

            % Extract display names from line objects
            for i=1:size(app.normPlot,1)
                Headers(i,1) = convertCharsToStrings(app.normPlot(i,1).DisplayName);
            end

            % Set data to UITable
            data = [subset Headers(:,1)];
            app.NormUITable.Data = data;
        end

        function updateNormalizedData(app)
            try
                % Find average at checked time slice
                locations = app.ShadeArea(app.normIndex,:);
            catch
                uialert(app.PIASpectraUIFigure,"Improper time slice selected. Please try again", "Time Slice Error","Icon","error");
                return
            end

            % Plot normalized data
            if app.FilterSwitch.Value == "On"

                % Find closest point to find index
                [~, indx1] = min(abs(app.FilteredX - locations(1)));
                [~, indx2] = min(abs(app.FilteredX - locations(2)));

                for i = 1:size(app.SortedYLinArray,2)
                    % Apply location of checked time slice to find avg
                    array2Avg(:,i) = app.FilteredY(indx1:indx2,i);
                    app.normFactor(i) = mean(array2Avg(:,i));

                    FilterY(:,i) = app.FilteredY(:,i)/app.normFactor(i);
                end

                for i = 1:size(app.normPlot,1)
                    app.normPlot(i).DisplayName = sprintf("Filtered %d nm",app.Plot_Wavelengths(i));
                    app.normPlot(i).XData = app.FilteredX;
                    app.normPlot(i).YData = FilterY(:,i);
                end

            else
                % Find closest point to find index of time slice
                [~, indx1] = min(abs(app.TimeLinSpecArray - locations(1)));
                [~, indx2] = min(abs(app.TimeLinSpecArray - locations(2)));

                for i = 1:size(app.SortedYLinArray,2)
                    % Apply location of checked time slice to find avg
                    array2Avg(:,i) = app.SortedYLinArray(indx1:indx2,i);
                    app.normFactor(i) = mean(array2Avg(:,i));

                    % Apply normaliztion to then be plotted
                    NormY(:,i) = app.SortedYLinArray(:,i)/(app.normFactor(i));
                end

                for i = 1:size(app.normPlot,1)
                    app.normPlot(i).DisplayName = sprintf("%d nm",app.Plot_Wavelengths(i));
                    app.normPlot(i).XData = app.TimeLinSpecArray;
                    app.normPlot(i).YData = NormY(:,i);
                end

            end
        end

        function updateSpecData(app,ind)
            app.specHandle(ind,1).XData = app.Plot_Wavelengths;
            app.specHandle(ind,1).YData = app.SpecDataPlot(:,ind);
        end

        function specYCheck(app)
            % Check if there are more than one tdms file opened. Change plot accordingly
            [m,~] = size(app.SpecDataPlot);
            if m > 1
                app.SpecYRange = [app.minMinSpecData app.maxMaxSpecData];
                app.SpecPlot.YLim = app.SpecYRange;
            else
                if app.Plot_Wavelengths(1) == app.maxWave % If there is only one wavelength?
                    app.SpecYRange = [app.minMinSpecData app.maxMaxSpecData];
                    xLim = [app.Plot_Wavelengths(1) app.Plot_Wavelengths(1)+1];

                    app.SpecPlot.YLim = app.SpecYRange;
                    app.SpecPlot.XLim = xLim;
                else
                    app.SpecYRange = [app.minMinSpecData app.maxMaxSpecData];
                    xLim = [app.Plot_Wavelengths(1) app.maxWave];

                    app.SpecPlot.YLim = app.SpecYRange;
                    app.SpecPlot.XLim = xLim;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hide unused GUI objects
            app.CenterPanel.Visible = false;
            app.RightPanel.Visible = false;
            app.ResetButton.Visible = false;
            app.ExportFiguresButton.Visible = false;
            app.NormUITable.Visible = false;

            % Set default boolean startup values
            app.rst = 0;

            try
                % Check if MATLAB addons are installed
                matlab.addons.isAddonEnabled('Image Processing Toolbox');
                matlab.addons.isAddonEnabled('Signal Processing Toolbox');
            catch error
                disp(error);
                app.LoadCreateError.Text = "Please ensure the 'Image Processing Toolbox' and 'Signal Processing Toolbox' are installed to continue";
                return;
            end
        end

        % Button pushed function: LoadTDMSButton
        function LoadTDMSButtonPushed(app, event)
            % Ensure there is no overlapping old data
            clearProperties(app);

            % Check if we start from startup fcn or reset fcn and set path accordingly
            if app.rst == 0
                app.Path = "";
                % Disable button until data is loaded
                app.LoadTDMSButton.Enable = 'off';
                try
                    app.LoadCreateError.Text = "Loading data...";
                    FolderPath = uigetdir;
                    FolderContent = dir(FolderPath); %#ok<NASGU>
                    uiwait(FolderPath);
                catch
                    app.LoadCreateError.Text = 'Invalid directory, try again';
                end
                app.LoadTDMSButton.Enable = 'on';
            else
                FolderPath = app.Path;
                FolderContent = dir(FolderPath); %#ok<NASGU>
            end

            % Load the appropriate script file to handle loaded TDMS data
            try
                PIASloadTDMSfolder;
%                 TESTloadTDMSfolder;
            catch ERROR
                switch ERROR.identifier
                    case 'MATLAB:UndefinedFunction'
                        app.LoadCreateError.Text = 'Function is not on selected path';
                        return;
                    otherwise
                        app.LoadCreateError.Text = 'Unknown error';
                        return;
                end
            end

            % Separate property to distinguish proper path
            app.Path = FolderPath;

            % Set all default variables from the loadTDMSfolder to public and
            % private properties for both applications to call on
            app.BackgroundLevel = BackgroundLvl;
            app.rst = 0;
            app.KinInd = 1;
            app.changeAxes = true;
            app.ShadeArea = [];

            % Reveal all the hidden objects
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;
            app.ResetButton.Visible = true;
            app.ExportFiguresButton.Visible = true;
            app.NormUITable.Visible = true;

            % Initizalize copy variables from loadTDMSfolder
            LinearData = LinData;
            LogarithmicData = LogData;

            % Set header variables to a property
            app.waves = LinearData.Properties.VariableNames(2:end);

            % Look at the data tables and sort by respective header
            waves_order = cellfun(@(x)sscanf(x, 'x%d'), app.waves);
            [~, sortedWavesOrder] = sort(waves_order);
            app.BackgroundLevel = app.BackgroundLevel(:,sortedWavesOrder);

            %Change sorting array to fit Time in first index
            sortedWavesOrder = sortedWavesOrder + 1;
            sortedWavesOrder = [1 sortedWavesOrder];
            app.LogDataSort = LogarithmicData(:, sortedWavesOrder);
            app.LinDataSort = LinearData(:, sortedWavesOrder);

            % And erase the useless 'x' char in wavelength num
            for i = 1:1:length(app.waves)
                app.waves(i) = erase(app.waves(i), 'x');
            end

            % Sort the plotted wavelengths and find max
            app.Plot_Wavelengths = sort(str2double(app.waves));
            app.maxWave = max(app.Plot_Wavelengths);

            % Populate properties to use throughout whole app
            app.dataRG = app.LinDataSort; % Using lin-lin now for PIAS
            app.timeRG = app.dataRG(:,1);

            % Turn useful data into arrays
            app.TimeLinSpecArray = table2array(app.timeRG);
            app.SortedYLinArray = table2array(app.dataRG(:,2:end));
            app.bkgLevel = table2array(app.BackgroundLevel(1,:));

            % Find the number of traces by subtracting 1 column for time
            app.Traces = size(app.LinDataSort, 2) - 1;

            % Output folder path in text box and continue
            [~, name] = fileparts(app.Path);
            app.LoadCreateError.Text = "Opened: " + name;

            % Find sample time
            app.sampleTime = app.TimeLinSpecArray(2,1) - app.TimeLinSpecArray(1,1);
            app.CurrentSampleTimesLabel.Text = "Current Sample Time (s): " + app.sampleTime;

            buildSpecData(app);
        end

        % Button pushed function: LoadCSVButton
        function LoadCSVButtonPushed(app, event)
            app.LoadCSVButton.Enable = 'off';
            % Find the csv file and record path and clear plots
            app.LoadCreateError.Text = "Loading data";
            [File, FilePath] = uigetfile('*.csv');
            app.LoadCreateError.Text = "Normalized data: " + File;
            
            try
                % Read the data and copy to public property to use
                opts = detectImportOptions(fullfile(FilePath, File));
                opts.VariableNamesLine = 1;
                temp = readtable(fullfile(FilePath, File),opts);
            catch error
                disp(error)
                app.LoadCreateError.Text = 'Function is not on selected path';
                app.LoadCSVButton.Enable = 'on';
                return;
            end

            app.LoadCSVButton.Enable = 'on';

            % Clear all previous properties and reset to default values
            clearProperties(app);
            app.Path = FilePath;
            app.FileName = File;
            % app.Path = "";

            % Re-apply temp variables to properties to keep useful data
            temp(1,:) = []; % Delete useless header row
            app.BackgroundLevel = temp(end,2:end); % Reset the background var
            temp(end,:) = []; % Delete useless background variable row in table

            % Set temp table back to main data property
            app.dataRG = temp;

            % Extract csv header names
            app.waves = app.dataRG.Properties.VariableNames(2:end);

            % Erase useless 'x' char in wavelength num
            for i = 1:1:length(app.waves)
                app.waves(i) = erase(app.waves(i), 'x');
            end

            % Reveal all the hidden objects
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;
            app.ResetButton.Visible = true;
            app.ExportFiguresButton.Visible = true;
            app.NormUITable.Visible = true;

            % Sort wavelength headers with files
            app.Plot_Wavelengths = sort(str2double(app.waves));
            app.maxWave = max(app.Plot_Wavelengths);

            % Turn useful data into arrays
            app.TimeLinSpecArray = table2array(app.dataRG(:,1));
            app.SortedYLinArray = table2array(app.dataRG(:,2:end));
            app.bkgLevel = table2array(app.BackgroundLevel(1,:));

            % Find the number of traces by subtracting 1 column for time
            app.Traces = size(app.dataRG, 2) - 1;

            buildSpecData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Create property to know when reset button is pushed
            app.rst = 1;

            % Save path of loaded data so you don't have to call data again
            % Then clear properties and reset to default
            tempPath = app.Path;
            clearProperties(app);
            app.Path = tempPath;

            LoadTDMSButtonPushed(app);
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            % Alter index accordingly and replot data
            app.KinInd = app.KinInd - 1;
            if app.KinInd < 1
                app.KinInd = 1;
                return
            end

            % Update the plot with new data
            updateData(app);
            plotKinData(app);
            FilterSwitchValueChanged(app);
            YCheck(app);
            updateAreas(app);

            plotSpecData(app,app.RGcolor); % Plot spectral data
            RGPlot(app,0); % Plot spectra data and reflect on UITable

            if app.FilterSwitch.Value == "Off"
                app.KinPlot.Children = [app.XPointsROI; app.DataPlot; app.KinRef];
            else
                app.KinPlot.Children = [app.XPointsROI; app.FilteredPlot; app.DataPlot; app.KinRef];
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Alter index accordingly and replot data
            app.KinInd = app.KinInd + 1;
            if app.KinInd > app.Traces
                app.KinInd = app.Traces;
                return
            end

            % Update the plot with new data
            updateData(app);
            plotKinData(app);
            FilterSwitchValueChanged(app);
            YCheck(app);
            updateAreas(app);
            plotSpecData(app,app.RGcolor); % Plot spectral data
            RGPlot(app,0); % Plot spectra data and reflect on UITable

            if app.FilterSwitch.Value == "Off"
                app.KinPlot.Children = [app.XPointsROI; app.DataPlot; app.KinRef];
            else
                app.KinPlot.Children = [app.XPointsROI; app.FilteredPlot; app.DataPlot; app.KinRef];
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            addRow(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Store important properties about the change like indices
            newData = event.NewData;
            indices = event.Indices;
            i = indices(1);

            % Only editing time fields column and not width
            if indices(2) == 2
                % Store old and new times field values
                previousTime = app.TimeFields(i);
                newTime = newData;

                % If the number doesn't change, do nothing
                if previousTime == newTime
                    return
                elseif isnan(newTime)
                    deleteRow(app, indices);
                    return
                end

                % Update the array spot with new data in double form
                app.TimeFields(i) = newTime;

                StartTime = newTime - (app.UITable.Data{i,3})/2;
                EndTime = newTime + (app.UITable.Data{i,3})/2;

                % Manually update each property with these new positions
                app.TimeWidths(i) = EndTime-StartTime;
                app.ShadeArea(i,:) = [StartTime EndTime];

                % Make sure the newly added/edited timeFields is sorted
                [app.TimeFields,I] = sort(app.TimeFields);
                app.TimeWidths = app.TimeWidths(I);

                % More efficient, it doesn't redraw everything, only deletes rectangles now
                Builder(app,1);
                rec = findobj(app.KinPlot,'Type','images.roi.Rectangle');
                delete(rec);
                drawAreas(app);
                RGPlot(app,i); % Plot spectra data and reflect on UITable

                % Editing time widths
            elseif indices(2) == 3
                % Store old width and new width in vars
                previousWidth = app.TimeWidths(i);
                newWidth = newData;

                % If the number doesn't change, do nothing
                if previousWidth == str2double(newData)
                    return
                elseif isnan(newData)
                    deleteRow(app, indices);
                    return
                end

                % Calculate the positions of the new width from the same centre
                StartTime = app.TimeFields(i) - (newWidth/2);
                EndTime = app.TimeFields(i) + (newWidth/2);

                % Manually update each property with these new positions
                app.TimeWidths(i) = EndTime-StartTime;
                app.ShadeArea(i,:) = [StartTime EndTime];
                app.XPointsROI(i,1).Position(1) = StartTime;
                app.XPointsROI(i,1).Position(3) = app.TimeWidths(i);

                Builder(app);
                RGPlot(app,i);
            elseif indices(2) == 1
                if newData == 1
                    app.checks = false(size(app.TimeFields));
                    app.checks(i,:) = true;

                    % Display new UITable values
                    data = num2cell(app.checks);
                    app.UITable.Data(:,1) = data;

                    app.normIndex = i;
                    updateNormalizedData(app);
                else
                    app.normIndex = 0;
                end
            end
        end

        % Cell edit callback: NormUITable
        function NormUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if strcmp(newData, '1') == 1 % If new box is checked
                app.normPlot(indices(1),1).Visible = true;
                app.normPlot(indices(1),1).HandleVisibility = "on";
            else
                app.normPlot(indices(1),1).Visible = false;
                app.normPlot(indices(1),1).HandleVisibility = "off";
            end
        end

        % Button pushed function: ExportSpectraDataButton
        function ExportSpectraDataButtonPushed(app, event)
            % Use path from data folder to export spectra data
            [~, name] = fileparts(app.Path);
            OutputName = app.Path + "\" + name + "_SPECTRADATA.csv";

            sorted_wavelengths = reshape(app.Plot_Wavelengths, [],1);
            wavelengths = num2str(sorted_wavelengths);
            wavelengths = string(wavelengths);
            DataToWrite = [wavelengths app.SpecDataPlot];

            % Create vars to export data to csv
            Headers = [];
            for i =1:1:size(app.SpecDataPlot,2)
                Headers(1,i+1) = mean(app.ShadeArea(i,:)); %#ok<AGROW>
            end

            % Concatenate data and write matrix to CSV
            OutputData = [Headers; DataToWrite];
            OutputData(1,1) = "Time Slice";

            writematrix(OutputData,OutputName,"QuoteStrings",true);

            % Reveal exported location for brief period of 10s
            app.ExportationLabel.Visible = true;
            app.ExportationLabel.Text = "Exported: " + OutputName;
            pause(10)
            app.ExportationLabel.Visible = false;
        end

        % Button pushed function: ExportCSVDataButton
        function ExportCSVDataButtonPushed(app, event)
            % Create file name/path to export to
            if strcmp(app.FileName,"") == 1
                [~, name] = fileparts(app.Path);
            else
                [~, name] = fileparts(app.FileName);
            end

            % Change code if filtered switch is selected
            if app.FilterSwitch.Value == "Off"
                % Change final name depending on if filtered or not
                loc = app.Path + "\" + name + "_KIN_PIAS.csv";
                normLoc = app.Path + "\" + name + "_NORM_KIN_PIAS.csv";

                % Set table export to unfiltered data
                tableData = app.dataRG;
            else
                % Change final name depending on if filtered or not
                loc = app.Path + "\" + name + "_KIN_PIAS_FILTERED.csv";
                normLoc = app.Path + "\" + name + "_NORM_KIN_PIAS_FILTERED.csv";

                % Preallocate array
                %                 filterY = zeros(size(app.SortedYLinArray,2));
                filterY = [];

                for i = 1:size(app.SortedYLinArray,2)
                    % Find the filtered data for each file
                    filterY(:,i) = decimate(app.SortedYLinArray(:,i),app.r);
                end

                % Set table export to filtered data
                tableData = [array2table(app.FilteredX) array2table(filterY)];
            end

            % Create identical variable to hold normalized datasu
            normData = tableData;

            % Normalize data and extract headers for table
            for i=1:size(app.normPlot,1)
                Headers(i) = convertCharsToStrings(app.normPlot(i).DisplayName);
                Headers(i) = erase(Headers(i), 'nm');

                normData{:,i+1} = tableData{:,i+1}/app.normFactor(1,i);
            end

            % Carry background level variable at the end of each table
            normData{end + 1, :} = 0;
            normData{end,2:end} = table2array(app.BackgroundLevel);% Could be more efficient means for this

            tableData{end + 1, :} = 0;
            tableData{end,2:end} = table2array(app.BackgroundLevel);% Could be more efficient means for this

            % Hardset x-value headers
            tableData.Properties.VariableNames(1) = "Time";
            normData.Properties.VariableNames(1) = "Time";

            % Hardset y-value headers
            tableData.Properties.VariableNames(2:end) = Headers(1,:);
            normData.Properties.VariableNames(2:end) = Headers(1,:);

            % Write unnormalized data to CSV
            writetable(tableData, loc,"Delimiter", ',', "QuoteStrings",true);
            % Write normalized data to CSV
            writetable(normData, normLoc, "Delimiter", ',', "QuoteStrings",true);

            % Reveal exported location for brief period of 10s
            app.ExportationLabel.Visible = true;
            app.ExportationLabel.Text = strcat('Exported to: ',loc);
            pause(10);
            try
                app.ExportationLabel.Visible = false;
            catch
                return;
            end
        end

        % Value changed function: FilterSwitch
        function FilterSwitchValueChanged(app, event)
            value = app.FilterSwitch.Value;
            tf = strcmp(value, 'On');

            % Remove previous filter plot and size of x,y variables
            delete(app.FilteredPlot);
            app.FilteredX = [];
            app.FilteredY = [];

            % Draw new ones or remove extra label
            if tf == 1
                % Find the constant filtered X-data depending on r value
                app.FilteredX = decimate(app.TimeLinSpecArray,app.r);

                for i = 1:size(app.SortedYLinArray,2)
                    % Find the filtered Y-data for each set depending on r
                    app.FilteredY(:,i) = decimate(app.SortedYLinArray(:,i),app.r);
                end

                app.FilteredPlot = line(app.KinPlot, app.FilteredX, app.FilteredY(:,app.KinInd), 'Color','r');

                % Turn on updated sample time label
                updated = app.r*app.sampleTime;
                app.UpdatedSampleTimesLabel.Text = "Updated Sample Time (s): " + updated;
            else
                % Turn off updated sample time label
                app.UpdatedSampleTimesLabel.Text = "Updated Sample Time (s): ";
            end

            updateNormalizedData(app);
            setNormalizedTable(app);
        end

        % Value changed function: DecimaterFactorEditField
        function DecimaterFactorEditFieldValueChanged(app, event)
            value = app.DecimaterFactorEditField.Value;

            % Round the inputted value and then set gui to reflect
            app.r = round(value);
            app.DecimaterFactorEditField.Value = app.r;

            app.FilterSwitchValueChanged(app);
        end

        % Button pushed function: ExportFiguresButton
        function ExportFiguresButtonPushed(app, event)
            % Create string for figure titles
            string = "@" + app.Plot_Wavelengths(app.KinInd);
            name1 = sprintf('KinFigure%s.emf',string);
            name2 = sprintf('SpecFigure%s.emf',string);
            name3 = sprintf('NormKinFigure%s.emf',string);

            % Create full path for each name
            place1 = fullfile(app.Path,name1);
            place2 = fullfile(app.Path,name2);
            place3 = fullfile(app.Path,name3);

            % Export Vector Image for KinPlot and PNG for SpecPlot
            exportgraphics(app.KinPlot, place1, 'ContentType','vector');
            exportgraphics(app.SpecPlot, place2, 'ContentType','vector');
            exportgraphics(app.NormKinPlot, place3, 'ContentType','vector');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PIASpectraUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {225, '1x', 257};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PIASpectraUIFigure = uifigure('Visible', 'off');
            app.PIASpectraUIFigure.Name = 'PIASpectra';
            app.PIASpectraUIFigure.Icon = 'logo.png';
            app.PIASpectraUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PIASpectraUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadTDMSButton = uibutton(app.GridLayout2, 'push');
            app.LoadTDMSButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTDMSButtonPushed, true);
            app.LoadTDMSButton.Text = 'Load TDMS';
            app.LoadCSVButton = uibutton(app.GridLayout2, 'push');
            app.LoadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVButtonPushed, true);
            app.LoadCSVButton.Text = 'Load CSV';
            app.LoadCreateError = uilabel(app.GridLayout2);
            app.LoadCreateError.Text = '';
            app.ExportationLabel = uilabel(app.GridLayout2);
            app.ExportationLabel.Text = '';
            app.NormUITable = uitable(app.GridLayout2);
            app.NormUITable.ColumnName = {'✓'; 'Normalized Lines'};
            app.NormUITable.RowName = {};
            app.NormUITable.ColumnEditable = [true false];
            app.NormUITable.CellEditCallback = createCallbackFcn(app, @NormUITableCellEdit, true);
            app.ExportFiguresButton = uibutton(app.GridLayout2, 'push');
            app.ExportFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFiguresButtonPushed, true);
            app.ExportFiguresButton.Text = 'Export Figures';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CenterPanel = uipanel(app.GridLayout);
            app.KinPlot = uiaxes(app.CenterPanel);
            title(app.KinPlot, {'Kinetics'; ''})
            xlabel(app.KinPlot, 'Time (s)')
            ylabel(app.KinPlot, 'ΔOD or %Abs')
            zlabel(app.KinPlot, 'Z')
            app.NormKinPlot = uiaxes(app.CenterPanel);
            title(app.NormKinPlot, {'Normalized Kinetics'; ''})
            xlabel(app.NormKinPlot, 'Time (s)')
            ylabel(app.NormKinPlot, 'Normalized ΔOD or %Abs')
            zlabel(app.NormKinPlot, 'Z')
            app.SpecPlot = uiaxes(app.CenterPanel);
            title(app.SpecPlot, 'Spectra')
            xlabel(app.SpecPlot, 'Wavelength (nm)')
            ylabel(app.SpecPlot, 'ΔOD or %Abs')
            zlabel(app.SpecPlot, 'Z')
            app.PrevButton = uibutton(app.CenterPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.CenterPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.FilterSwitchLabel = uilabel(app.CenterPanel);
            app.FilterSwitchLabel.Text = 'Filter';
            app.FilterSwitch = uiswitch(app.CenterPanel, 'rocker');
            app.FilterSwitch.ValueChangedFcn = createCallbackFcn(app, @FilterSwitchValueChanged, true);
            app.FilterfactorLabel = uilabel(app.CenterPanel);
            app.FilterfactorLabel.Text = 'Decimate r Factor:';
            app.DecimaterFactorEditField = uieditfield(app.CenterPanel, 'numeric');
            app.DecimaterFactorEditField.ValueChangedFcn = createCallbackFcn(app, @DecimaterFactorEditFieldValueChanged, true);
            app.DecimaterFactorEditField.Value = 10;
            app.CurrentSampleTimesLabel = uilabel(app.CenterPanel);
            app.CurrentSampleTimesLabel.Text = 'Current Sample Time (s):';
            app.UpdatedSampleTimesLabel = uilabel(app.CenterPanel);
            app.UpdatedSampleTimesLabel.Text = 'Updated Sample Time (s):';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.PlotDetailsLabel = uilabel(app.GridLayout4);
            app.PlotDetailsLabel.Text = 'Plot Details';
            app.Description = uilabel(app.GridLayout4);
            app.Description.Text = 'Center times & widths for spectra';
            app.AddButton = uibutton(app.GridLayout4, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'✓'; 'Centre Time (s)'; 'Time Width (s)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.BackgroundLvlDisplay = uilabel(app.GridLayout4);
            app.BackgroundLvlDisplay.Text = 'Background level is _ V';
            app.KineticLevelDisplay = uilabel(app.GridLayout4);
            app.KineticLevelDisplay.Text = 'Displaying kinetic at _ nm';
            app.ExportCSVDataButton = uibutton(app.GridLayout4, 'push');
            app.ExportCSVDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVDataButtonPushed, true);
            app.ExportCSVDataButton.Text = 'Export CSV Data';
            app.ExportSpectraDataButton = uibutton(app.GridLayout4, 'push');
            app.ExportSpectraDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSpectraDataButtonPushed, true);
            app.ExportSpectraDataButton.Text = 'Export SpectraData';
            app.Channel = uilabel(app.GridLayout4);
            app.Channel.Text = '';
            app.FileMetadataLabel = uilabel(app.GridLayout4);
            app.FileMetadataLabel.Text = 'File Metadata';
            % Show the figure after all components are created
            app.PIASpectraUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIASpectra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PIASpectraUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PIASpectraUIFigure)
        end
    end
end